classdef predict_trajectory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        RunButton                matlab.ui.control.Button
        EditField                matlab.ui.control.NumericEditField
        EditField_2              matlab.ui.control.NumericEditField
        EditField_3              matlab.ui.control.NumericEditField
        EditField_4              matlab.ui.control.NumericEditField
        AmatrixLabel             matlab.ui.control.Label
        QmatrixLabel             matlab.ui.control.Label
        EditField_5              matlab.ui.control.NumericEditField
        EditField_6              matlab.ui.control.NumericEditField
        EditField_7              matlab.ui.control.NumericEditField
        EditField_8              matlab.ui.control.NumericEditField
        SelecttaskDropDownLabel  matlab.ui.control.Label
        SelecttaskDropDown       matlab.ui.control.DropDown
    end


    properties (Access = private)
        datafile; % Description
        task = 0; % Description
    end
    
    methods (Access = private)
        
        function ret = getAmat(app)
            ret = [app.EditField.Value, app.EditField_2.Value; ...
                app.EditField_3.Value, app.EditField_4.Value];
        end
        
        function ret = getQmat(app)
            ret = [app.EditField_5.Value, app.EditField_6.Value; ...
                app.EditField_7.Value, app.EditField_8.Value];
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            A = getAmat(app);
            Q = getQmat(app);
            if app.task>0
                spikes = app.datafile{1}; 
                dt = app.datafile{2}; 
                cosine_fit = app.datafile{3}; 
                arm_vel = app.datafile{4}; 
                % B regression coefficients
                B = cosine_fit.Bs;
                % R NxN matrix of noise distribution from regression
                R = cosine_fit.sigma;
                sm = 2; %smoothing value
                %Mean of velocity initial time step, dimension x 1, initialize at zero
                mu = cell(size(spikes));
                mu(:) = {zeros(2, 1)};
                %Covariance matrix of velocity initial time step
                phi = zeros(2, 2);
                
                for traj = 1:size(spikes, 2)
                    for j = 1:size(spikes{traj}, 2)
                        if mod(sm, 2) == 0
                            mnarr = spikes{traj}(:, max(j-sm/2, 1):min(j+sm/2, size(spikes{traj}, 2)));
                        else
                            mnarr = [mean(spikes{traj}(:, max(j-floor(sm/2)-1, 1):max(j-floor(sm/2), 1)), 2) ...
                                spikes{traj}(:, max(j-floor(sm/2), 1):min(j+floor(sm/2), size(spikes{traj}, 2))) ...
                                mean(spikes{traj}(:, min(j+floor(sm/2), size(spikes{traj}, 2)):min(j+floor(sm/2)+1, size(spikes{traj}, 2))), 2)];
                        end
                        %smooth spike rates
                        %as well but it seems like it should...
                        sm_f0(:, j) = mean(mnarr, 2)/dt-cosine_fit.b0s;
                    end
                    for t = 1:size(spikes{traj}, 2)
                        % one step prediction
                        mu_est = A * mu{traj}(:, t);
                        phi_est = A * phi * A' + Q;
                        
                        % Kalman Gain
                        K = phi * B' * inv(B*phi*B'+R);
                        
                        % Update the state estimate.
                        mu_est = mu_est + K * (sm_f0(:, t) - B*mu_est);
                        % update covariance estimation.
                        phi_est = phi_est - K * B * phi_est;
                        
                        mu{traj}(:, t+1) = mu_est;
                        phi = phi_est;
                    end
                    mu_vel{traj} = mu{traj}(:, 2:end)';
                end
                
            %TODO: plot the actual arm velocities and the predicted
            hold(app.UIAxes_2, 'off');
            plot(app.UIAxes_2, arm_vel{2}(:, 1), arm_vel{2}(:, 2), 'b', 'LineWidth', 2); 
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, mu_vel{2}(:, 1), mu_vel{2}(:, 2), ':r', 'LineWidth', 2); 
            legend({'Actual Reach', 'Predicted Reach'}); 
                
            elseif app.task == 0 
                disp('You have not yet set the task');
            end
            
        end

        % Value changed function: SelecttaskDropDown
        function SelecttaskDropDownValueChanged(app, event)
            value = app.SelecttaskDropDown.Value;
            if value == '2D reach task'
                load('ps5_data_2D.mat');
                disp(cosine_fit); 
                app.datafile = {spikes, dt, cosine_fit, arm_vel};
                app.task = 1; 
                %TODO: plot the data and actual trajectory
            end
            %TODO: add additional tasks here
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Prediction')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 1;
            app.AmatrixLabel = uilabel(app.UIFigure);
            app.AmatrixLabel.Text = 'A matrix';
            app.QmatrixLabel = uilabel(app.UIFigure);
            app.QmatrixLabel.Text = 'Q matrix';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 1;
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Value = 1;
            app.SelecttaskDropDownLabel = uilabel(app.UIFigure);
            app.SelecttaskDropDownLabel.Text = 'Select task:';
            app.SelecttaskDropDown = uidropdown(app.UIFigure);
            app.SelecttaskDropDown.Items = {' ', '2D reach task', 'Option 3', 'Option 4'};
            app.SelecttaskDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecttaskDropDownValueChanged, true);
            app.SelecttaskDropDown.Value = ' ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = predict_trajectory

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end